classdef MATLAB_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ControlTab                     matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        SpeedValueLabel                matlab.ui.control.Label
        CurrentSpeedLabel              matlab.ui.control.Label
        StateValueLabel                matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        TestSwitch                     matlab.ui.control.Switch
        ModeKnob                       matlab.ui.control.DiscreteKnob
        ReceivingLabel                 matlab.ui.control.Label
        SendingLabel                   matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        SpeedmmminEditField            matlab.ui.control.NumericEditField
        SpeedmmminEditFieldLabel       matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        EreaseDataButton               matlab.ui.control.Button
        PlotTestDataButton             matlab.ui.control.Button
        StoreTestDataButton            matlab.ui.control.Button
        RealtimePlotCheckBox           matlab.ui.control.CheckBox
        ElongationEndValueumEditField  matlab.ui.control.NumericEditField
        ElongationEndValueumEditFieldLabel  matlab.ui.control.Label
        LoadEndValueNEditField         matlab.ui.control.NumericEditField
        LoadEndValueNEditFieldLabel    matlab.ui.control.Label
        ElongValueLabel                matlab.ui.control.Label
        LoadValueLabel                 matlab.ui.control.Label
        CurrentElongationLabel         matlab.ui.control.Label
        CurrentLoadLabel               matlab.ui.control.Label
        CurrentStateLabel              matlab.ui.control.Label
        ReadingLampLabel               matlab.ui.control.Label
        ReadingLamp                    matlab.ui.control.Lamp
        WritingLabel                   matlab.ui.control.Label
        WritingLamp                    matlab.ui.control.Lamp
        ConnectedLampLabel             matlab.ui.control.Label
        ConnectedLamp                  matlab.ui.control.Lamp
        DataAxes                       matlab.ui.control.UIAxes
        CommunicationSettingsTab       matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        Panel_5                        matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        NofdatainRTplotEditField       matlab.ui.control.NumericEditField
        NofdatainRTplotEditFieldLabel  matlab.ui.control.Label
        ElongationEditField            matlab.ui.control.EditField
        ElongationEditFieldLabel       matlab.ui.control.Label
        LoadEditField                  matlab.ui.control.EditField
        LoadEditFieldLabel             matlab.ui.control.Label
        SpeedEditField                 matlab.ui.control.EditField
        SpeedEditFieldLabel            matlab.ui.control.Label
        DATATYPELabel                  matlab.ui.control.Label
        CONNECTButton                  matlab.ui.control.Button
        Panel_4                        matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        LastTimeStampValueLabel        matlab.ui.control.Label
        LastTimeStampmsLabel           matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        ACKEditField                   matlab.ui.control.NumericEditField
        ACKEditFieldLabel              matlab.ui.control.Label
        StartByteEditField             matlab.ui.control.NumericEditField
        StartByteEditFieldLabel        matlab.ui.control.Label
        BaudRateEditField              matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel         matlab.ui.control.Label
        COMEditField                   matlab.ui.control.NumericEditField
        COMEditFieldLabel              matlab.ui.control.Label
        ARDUINOSETTINGSLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        arduinoObj   %Arduino
        port="COM5"       %Arduino com port
        baudRate=115200;  %communication Baud Rate
        isConnected = 0;    %true while arduino connected
        
        DATA_IN_LENGHT = 11              %without start
        START_BYTE = uint8(99);          %default value of start of comm. byte
        ACK_BYTE = uint8(98);            %defaul acknowledgement byte
        onRead = 1                       %1 if reading is possible


        %DataIn : data received from arduino, 3 bytes 
        arduino_speed %speed from arduino 
        time %
        state  % current arduino operative state/mode
        load  %  current measured load
        ext   %  current measured elongation%
        
        %data acquisition history
        ext_h = []
        load_h = []
        time_h = []
        speed_h = []
        samples = 0
        x=[]
        
        %array used to plot data
        ext_p =[]
        load_p = []
        time_p= []
        MAX_P_LENGTH = 500 %max data plotted during realtime plot
        visCounter = 0;    %if visCounter = MAX_VIS_COUNT -> plot refresh
        MAX_VIS_COUNTER = 1;
        
        
        % dataOut    :  data sent to arduino , 7 bytes
        
        testing = 0;          % if 1 start test
        testMode = 3;
        next_state=uint8(0);   % desired next arduino operative state/mode
        max_load  =uint16(0);   % machine stop at max_load
        max_ext   =uint16(0);   % machine stop at max_load%
        speed     = uint16(0);  % speed of machine
        dataOut = [] 
        
        %CTRL MODES%
        CTRLbyELONG = uint8(3)% stop when  max_exp is reached
        CTRLbyLOAD  = uint8(4)% stop when  max_load is reached
        MANUAL      = uint8(0)% manual harware control


    end
    
    methods (Access = private)
       
        %setup serial communication with arduino
        function  serialBegin( app, port, baudrate)
            app.arduinoObj = serialport(port, baudrate)
            flush(app.arduinoObj);
            app.ConnectedLamp.Color = 'g';
        end
         %_______________________________%
            % SEND
        
        %_______________________________%
        %send bytes and search for acknowledgement
        function serialWrite(app, data)
           done = 0;
           i=0;
           fprintf("Sending\n")
           while (~done) & (i<1000)
               %write data
               
               app.WritingLamp.Color = 'y';
               write(app.arduinoObj,data,"uint8")     
               i = i+1;
               
               %show transmitted data
               s = num2str(data(1));
               for l =2 : length(data)
                   s = strcat(s,".",num2str(data(l)));
               end
               app.SendingLabel.Text = num2str(s);
               
               %search ack
               ack = read(app.arduinoObj,1, "uint8"); 
               if ack == app.ACK_BYTE 
                   fprintf("ACK find\n")
                   done = 1;
                   app.ReadingLamp.Color='w';  
                   app.ReceivingLabel.Text = "End of Communication";
                   
               end
               
           end 
               app.WritingLamp.Color= 'w';
               
        end
        
        
        %________________________________________________
        %         get Arduino State and sensor Data
        %________________________________________________
        function  getData(app)
            app.onRead = 1;
            
            while app.onRead == 1 
                    start = read(app.arduinoObj,1,"uint8");
                    app.ReadingLamp.Color='y';
                    if start == app.START_BYTE
                        
                        try
                            data = read(app.arduinoObj,app.DATA_IN_LENGHT, "uint8");
                        catch
                            return
                        end
                        app.state = uint8(data(1));
                        %we need to combine byte to form bigger data
                        mulSpeedR = (uint16(data(2))*2^8+data(3));
                        app.arduino_speed = mulSpeedR/100;
                        app.time = double((uint32(data(4))*2^24+uint32(data(5))*2^16+uint32(data(6))*2^8+data(7)))
                                        
                        app.load =(uint16(data(8))*2^8+data(9));
                        app.ext = (uint16(data(10))*2^8+data(11));
                        app.ReceivingLabel.Text= strcat(num2str(data(1)),".",num2str(data(2)),".",num2str(data(3)), ...
                                                        ".",num2str(data(4)),".",num2str(data(5)),".",num2str(data(6)),...
                                                        ".",num2str(data(7)),".",num2str(data(8)),".",num2str(data(9)),...
                                                        ".",num2str(data(10)),".",num2str(data(11)));
                        
                        app.StateValueLabel.Text = num2str(app.state);
                        app.SpeedValueLabel.Text = num2str(app.arduino_speed);
                        app.LoadValueLabel.Text = num2str(app.load);
                        app.ElongValueLabel.Text = num2str(app.ext);
                        app.LastTimeStampValueLabel.Text = num2str(app.time);


                        %now add data to history
                        if app.testing ==1 
                            app.load_h(end+1) = app.load;
                            app.ext_h(end+1) = app.ext;
                            app.time_h(end+1) = (app.time/1000); %[s]
                            app.samples=app.samples+1;
                            app.x=[1:app.samples];
                            app.speed_h(end+1)= app.arduino_speed;


                        end
                        
                        if app.RealtimePlotCheckBox.Value==1
                            app.load_p = add2plottable(app, app.load_p,app.load);
                            app.ext_p  = add2plottable(app, app.ext_p,app.ext);
                            app.time_p = add2plottable(app, app.time_p, app.time/1000);
                            app.visCounter = app.visCounter+1;
                            if app.visCounter == app.MAX_VIS_COUNTER
                                app.visCounter = 0
%                                 plot(app.DataAxes,app.time_p,app.load_p,'r-', app.time_p, app.ext_p,'b-');
%                                 legend(app.DataAxes,"Load [N]", "Elongation [um]");
%                                 xlabel(app.DataAxes, "time [s]"); 
                                plotData(app, app.time_p, app.load_p, app.ext_p)
                            end
                        end
                        
                        
                        %check end of test
                        if app.state == 9 
                            app.next_state = 0;
                            app.TestSwitch.Value = 0;
                            app.ModeKnob.Enable = 1;  %activate test mode sel 
                        end
                    end
            end
        end
        
%%____________________________________________________________%
 %                        UPDATE DATA OUT                           
%%____________________________________________________________%
        
        %Update control command%
        function updateDataOut(app)
            %ns = app.next_state
            
            %we need to send bytes, so we must split bigger data
            max_load_H = uint8(bitsrl(uint16(app.max_load),8)); %most significative byte
            max_load_L = uint8(bitand(app.max_load,255)); %least significative byte
            
            max_ext_H = uint8(bitsrl(uint16(app.max_ext),8)); %most significative byte
            max_ext_L = uint8(bitand(app.max_ext,255));  %least significative byte
            
            mulSpeed = app.speed*100;
            mulSpeed_H = uint8(bitsrl(uint16(mulSpeed),8)); %most significative byte
            mulSpeed_L = uint8(bitand(mulSpeed,255)); %least significative byte
            
            %building up dataOut
            app.dataOut = [app.START_BYTE, app.next_state, mulSpeed_H, mulSpeed_L, max_load_H, max_load_L, max_ext_H, max_ext_L]; 
            data = app.dataOut;
        end


        %stop reading%
        function stopRead(app)
            app.onRead = 0;
            app.ReadingLamp.Color='w';
        end
        
        %update control and restart reading%
        function update(app)
            stopRead(app)
            updateDataOut(app)
            serialWrite(app, app.dataOut)
            getData(app);
            grid(app.DataAxes,'on')
        end
        
        %erease read/write bytes labels
        function ereaseRWLabel(app)
            app.ReceivingLabel.Text="";
            app.SendingLabel.Text="";
        end
        
        %erease data history (before a test)
        function  ereaseDataHistory(app)
            app.ext_h=[];
            app.load_h=[];
            app.time_h=[];
            app.samples=0;
            
            app.time_p = [];
            app.load_p = [];
            app.ext_p = [];
        end
        
        function plotData(app, xdata_time, ydata_load, ydata_elong)
            
            
            %clear axes
            hold(app.DataAxes,"off");
            %write new data
            yyaxis(app.DataAxes, 'left');
            plot(app.DataAxes,xdata_time,ydata_load);
           
            
            yyaxis(app.DataAxes, 'right');
            plot(app.DataAxes, xdata_time, ydata_elong);
            legend(app.DataAxes,"Load", "Elongation")
        end


        function connectArduino(app)
            
            %connect device
            stopRead(app)
            serialBegin(app, app.port , app.baudRate);
            app.testMode  = str2num(app.ModeKnob.Value);
            fprintf("Connesso alla porta %s", app.port)
            %change connection button appearance
            
            app.CONNECTButton.Text = "DISCONNECT";
            app.CONNECTButton.BackgroundColor = [0.64,0.08,0.18];
            %arduino is now connected
            app.isConnected=1; 
            update(app);
        end
        
        function disconnectArduino(app)
            %disconnect device
            stopRead(app)
            app.arduinoObj=[];
            fprintf("Disconnesso")
            app.isConnected=0;
            %change connection button appearance
            app.CONNECTButton.Text = "CONNECT";
            app.CONNECTButton.BackgroundColor = [0.47,0.67,0.19];
            
        end


        function a = add2plottable(app, a,data)
            if length(a) >= app.MAX_P_LENGTH
                st = length(a)-app.MAX_P_LENGTH+2;
                a = a(st:end);
            end
            a(end+1) = data;
        end
        
        function ereaseGraphic(app, axes)
            
            
            hold(axes,'off');
            yyaxis(axes,"left");
            plot(axes,NaN,NaN);
            yyaxis(axes,'right');
            plot(axes,NaN,NaN);
            hold(axes,'on');
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.testMode  = str2num(app.ModeKnob.Value);
           app.port = strcat("COM", num2str(app.COMEditField.Value));
           
           %axes setup
           title(app.DataAxes, "Measurements")
           grid(app.DataAxes,'on')
           xlabel(app.DataAxes,"time [s]")
           yyaxis(app.DataAxes,"left")
           ylabel(app.DataAxes, "Load [N]")
           yyaxis(app.DataAxes,"right")
           ylabel(app.DataAxes,"Elongation [\mum]")
           
        end

        % Value changed function: ElongationEndValueumEditField
        function ElongationEndValueumEditFieldValueChanged(app, event)
            app.max_ext = app.ElongationEndValueumEditField.Value;
            %update(app);
        end

        % Value changed function: LoadEndValueNEditField
        function LoadEndValueNEditFieldValueChanged(app, event)
            app.max_load = app.LoadEndValueNEditField.Value;
            %update(app); 
        end

        % Value changed function: TestSwitch
        function TestSwitchValueChanged(app, event)
            app.testing = app.TestSwitch.Value;
            if app.testing==1
                stopRead(app);
                ereaseDataHistory(app);
                app.next_state=uint8(app.testMode);
                app.ModeKnob.Enable = 0;
            else
                app.next_state=uint8(0);
                app.ModeKnob.Enable = 1;
            end
            update(app)
            %IF STATE =TRIG THEN PLOT
        end

        % Button pushed function: StoreTestDataButton
        function StoreTestDataButtonPushed(app, event)
            load_h = app.load_h;
            ext_h = app.ext_h;
            time_h = app.time_h;
            samples= app.samples;
            speed = app.arduino_speed;
            
           
            uisave({'load_h', 'ext_h', 'time_h','samples', 'speed' },'Test')
        end

        % Button pushed function: PlotTestDataButton
        function PlotTestDataButtonPushed(app, event)
            app.RealtimePlotCheckBox.Value = 0;
            ereaseGraphic(app, app.DataAxes);
            plotData(app, app.time_h, app.load_h, app.ext_h);
        end

        % Value changed function: SpeedmmminEditField
        function SpeedmmminEditFieldValueChanged(app, event)
            app.speed = app.SpeedmmminEditField.Value;
            %update(app); 
        end

        % Value changed function: COMEditField
        function COMEditFieldValueChanged(app, event)
            app.port = strcat("COM", num2str(app.COMEditField.Value));
            disconnectArduino(app) %diconnect arduino to ensure correct connection

        end

        % Value changed function: ModeKnob
        function ModeKnobValueChanged(app, event)
            app.testMode = str2num(app.ModeKnob.Value);
        end

        % Value changed function: BaudRateEditField
        function BaudRateEditFieldValueChanged(app, event)
            app.baudRate = app.BaudRateEditField.Value;
            disconnectArduino(app) %diconnect arduino to ensure correct connection
        end

        % Value changed function: ACKEditField
        function ACKEditFieldValueChanged(app, event)
            app.ACK_BYTE = app.ACKEditField.Value;
            update(app); 
        end

        % Value changed function: StartByteEditField
        function StartByteEditFieldValueChanged(app, event)
            app.START_BYTE = app.StartByteEditField.Value;
            update(app);
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
            if app.isConnected == 0
                connectArduino(app)
                
            else
                disconnectArduino(app)
            end
            
            
        end

        % Value changed function: SpeedEditField
        function SpeedEditFieldValueChanged(app, event)
           
        end

        % Button pushed function: EreaseDataButton
        function EreaseDataButtonPushed(app, event)
            ereaseGraphic(app, app.DataAxes)
            ereaseDataHistory(app)
        end

        % Value changed function: NofdatainRTplotEditField
        function NofdatainRTplotEditFieldValueChanged(app, event)
            app.MAX_P_LENGTH = app.NofdatainRTplotEditField.Value;
            app.time_p = [];
            app.load_p = [];
            app.ext_p = [];
            
        end

        % Value changed function: RealtimePlotCheckBox
        function RealtimePlotCheckBoxValueChanged(app, event)

            app.time_p = [];
            app.load_p = [];
            app.ext_p = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.GridLayout = uigridlayout(app.ControlTab);
            app.DataAxes = uiaxes(app.GridLayout);
            title(app.DataAxes, 'Data')
            xlabel(app.DataAxes, 'Time [s]')
            ylabel(app.DataAxes, 'Value')
            zlabel(app.DataAxes, 'Z')
            app.ConnectedLamp = uilamp(app.GridLayout);
            app.ConnectedLampLabel = uilabel(app.GridLayout);
            app.ConnectedLampLabel.Text = 'Connected';
            app.WritingLamp = uilamp(app.GridLayout);
            app.WritingLabel = uilabel(app.GridLayout);
            app.WritingLabel.Text = 'Writing';
            app.ReadingLamp = uilamp(app.GridLayout);
            app.ReadingLampLabel = uilabel(app.GridLayout);
            app.ReadingLampLabel.Text = 'Reading';
            app.CurrentStateLabel = uilabel(app.GridLayout);
            app.CurrentStateLabel.Text = 'Current State';
            app.CurrentLoadLabel = uilabel(app.GridLayout);
            app.CurrentLoadLabel.Text = 'Current Load';
            app.CurrentElongationLabel = uilabel(app.GridLayout);
            app.CurrentElongationLabel.Text = 'Current Elongation';
            app.LoadValueLabel = uilabel(app.GridLayout);
            app.LoadValueLabel.Text = 'LoadValue';
            app.ElongValueLabel = uilabel(app.GridLayout);
            app.ElongValueLabel.Text = 'ElongValue';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LoadEndValueNEditFieldLabel = uilabel(app.GridLayout2);
            app.LoadEndValueNEditFieldLabel.Text = 'Load End Value [N]';
            app.LoadEndValueNEditField = uieditfield(app.GridLayout2, 'numeric');
            app.LoadEndValueNEditField.ValueChangedFcn = createCallbackFcn(app, @LoadEndValueNEditFieldValueChanged, true);
            app.ElongationEndValueumEditFieldLabel = uilabel(app.GridLayout2);
            app.ElongationEndValueumEditFieldLabel.Text = 'Elongation End Value [um]';
            app.ElongationEndValueumEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ElongationEndValueumEditField.ValueChangedFcn = createCallbackFcn(app, @ElongationEndValueumEditFieldValueChanged, true);
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.RealtimePlotCheckBox = uicheckbox(app.GridLayout4);
            app.RealtimePlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @RealtimePlotCheckBoxValueChanged, true);
            app.RealtimePlotCheckBox.Text = 'Real-time Plot';
            app.StoreTestDataButton = uibutton(app.GridLayout4, 'push');
            app.StoreTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @StoreTestDataButtonPushed, true);
            app.StoreTestDataButton.Text = 'Store Test Data';
            app.PlotTestDataButton = uibutton(app.GridLayout4, 'push');
            app.PlotTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTestDataButtonPushed, true);
            app.PlotTestDataButton.Text = 'Plot Test Data';
            app.EreaseDataButton = uibutton(app.GridLayout4, 'push');
            app.EreaseDataButton.ButtonPushedFcn = createCallbackFcn(app, @EreaseDataButtonPushed, true);
            app.EreaseDataButton.Text = 'Erease Data';
            app.SpeedmmminEditFieldLabel = uilabel(app.GridLayout2);
            app.SpeedmmminEditFieldLabel.Text = 'Speed [mm/min]';
            app.SpeedmmminEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SpeedmmminEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedmmminEditFieldValueChanged, true);
            app.SendingLabel = uilabel(app.GridLayout);
            app.SendingLabel.Text = 'Sending';
            app.ReceivingLabel = uilabel(app.GridLayout);
            app.ReceivingLabel.Text = 'Receiving';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.ModeKnob = uiknob(app.GridLayout3, 'discrete');
            app.ModeKnob.Items = {'Ctrl By Load', 'Ctrl By Elongation'};
            app.ModeKnob.ItemsData = {'3', '4'};
            app.ModeKnob.ValueChangedFcn = createCallbackFcn(app, @ModeKnobValueChanged, true);
            app.ModeKnob.Value = '3';
            app.TestSwitch = uiswitch(app.GridLayout3, 'slider');
            app.TestSwitch.Items = {'STOP', 'START TEST'};
            app.TestSwitch.ItemsData = [0 1];
            app.TestSwitch.ValueChangedFcn = createCallbackFcn(app, @TestSwitchValueChanged, true);
            app.TestSwitch.Value = 0;
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = 'Logo.png';
            app.StateValueLabel = uilabel(app.GridLayout);
            app.StateValueLabel.Text = 'StateValue';
            app.CurrentSpeedLabel = uilabel(app.GridLayout);
            app.CurrentSpeedLabel.Text = 'Current Speed';
            app.SpeedValueLabel = uilabel(app.GridLayout);
            app.SpeedValueLabel.Text = 'SpeedValue';
            app.CommunicationSettingsTab = uitab(app.TabGroup);
            app.CommunicationSettingsTab.Title = 'Communication Settings';
            app.GridLayout5 = uigridlayout(app.CommunicationSettingsTab);
            app.Panel_3 = uipanel(app.GridLayout5);
            app.GridLayout6 = uigridlayout(app.Panel_3);
            app.ARDUINOSETTINGSLabel = uilabel(app.GridLayout6);
            app.ARDUINOSETTINGSLabel.Text = 'ARDUINO SETTINGS';
            app.COMEditFieldLabel = uilabel(app.GridLayout6);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.GridLayout6, 'numeric');
            app.COMEditField.ValueChangedFcn = createCallbackFcn(app, @COMEditFieldValueChanged, true);
            app.COMEditField.Value = 5;
            app.BaudRateEditFieldLabel = uilabel(app.GridLayout6);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.GridLayout6, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.ValueChangedFcn = createCallbackFcn(app, @BaudRateEditFieldValueChanged, true);
            app.BaudRateEditField.Value = 115200;
            app.StartByteEditFieldLabel = uilabel(app.GridLayout6);
            app.StartByteEditFieldLabel.Text = 'Start Byte';
            app.StartByteEditField = uieditfield(app.GridLayout6, 'numeric');
            app.StartByteEditField.ValueChangedFcn = createCallbackFcn(app, @StartByteEditFieldValueChanged, true);
            app.StartByteEditField.Value = 99;
            app.ACKEditFieldLabel = uilabel(app.GridLayout6);
            app.ACKEditFieldLabel.Text = 'ACK';
            app.ACKEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ACKEditField.ValueChangedFcn = createCallbackFcn(app, @ACKEditFieldValueChanged, true);
            app.ACKEditField.Value = 98;
            app.Panel_4 = uipanel(app.GridLayout5);
            app.GridLayout7 = uigridlayout(app.Panel_4);
            app.LastTimeStampmsLabel = uilabel(app.GridLayout7);
            app.LastTimeStampmsLabel.Text = 'Last Time Stamp [ms]';
            app.LastTimeStampValueLabel = uilabel(app.GridLayout7);
            app.LastTimeStampValueLabel.Text = 'Last Time Stamp Value';
            app.CONNECTButton = uibutton(app.GridLayout5, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.Panel_5 = uipanel(app.GridLayout5);
            app.GridLayout8 = uigridlayout(app.Panel_5);
            app.DATATYPELabel = uilabel(app.GridLayout8);
            app.DATATYPELabel.Text = 'DATA TYPE';
            app.SpeedEditFieldLabel = uilabel(app.GridLayout8);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.GridLayout8, 'text');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedEditFieldValueChanged, true);
            app.SpeedEditField.Value = 'to do ';
            app.LoadEditFieldLabel = uilabel(app.GridLayout8);
            app.LoadEditFieldLabel.Text = 'Load';
            app.LoadEditField = uieditfield(app.GridLayout8, 'text');
            app.LoadEditField.Value = 'to do ';
            app.ElongationEditFieldLabel = uilabel(app.GridLayout8);
            app.ElongationEditFieldLabel.Text = 'Elongation';
            app.ElongationEditField = uieditfield(app.GridLayout8, 'text');
            app.ElongationEditField.Value = 'to do ';
            app.NofdatainRTplotEditFieldLabel = uilabel(app.GridLayout8);
            app.NofdatainRTplotEditFieldLabel.Text = 'N° of data in RT plot';
            app.NofdatainRTplotEditField = uieditfield(app.GridLayout8, 'numeric');
            app.NofdatainRTplotEditField.ValueChangedFcn = createCallbackFcn(app, @NofdatainRTplotEditFieldValueChanged, true);
            app.NofdatainRTplotEditField.Value = 200;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLAB_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end